classdef Strowanie < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DaneprzywczytywaniuzplikupojawiajasiepoutworzeniuwykresuLabel  matlab.ui.control.Label
        CzassEditField              matlab.ui.control.NumericEditField
        CzassEditFieldLabel         matlab.ui.control.Label
        DrogamEditField             matlab.ui.control.NumericEditField
        DrogamEditFieldLabel        matlab.ui.control.Label
        Zpowrotemdo0Button          matlab.ui.control.StateButton
        UwagiLabel                  matlab.ui.control.Label
        ZplikuButton_2              matlab.ui.control.StateButton
        StopButton                  matlab.ui.control.Button
        SymulacjazplikuButton_2     matlab.ui.control.Button
        UtworzwykresButton          matlab.ui.control.Button
        numerkrokuEditField         matlab.ui.control.NumericEditField
        numerkrokuEditFieldLabel    matlab.ui.control.Label
        ImportzplikuxlsxLabel       matlab.ui.control.Label
        Import                      matlab.ui.control.Button
        PozycjazEditField           matlab.ui.control.EditField
        PozycjaxEditField           matlab.ui.control.EditField
        PozycjayEditField           matlab.ui.control.EditField
        KolejnodziaaniaButtonGroup  matlab.ui.container.ButtonGroup
        SymultanicznieButton        matlab.ui.control.ToggleButton
        ZXYButton                   matlab.ui.control.ToggleButton
        XYZButton                   matlab.ui.control.ToggleButton
        SymulacjaButton             matlab.ui.control.Button
        ResetButton                 matlab.ui.control.Button
        zEditField                  matlab.ui.control.NumericEditField
        zEditFieldLabel             matlab.ui.control.Label
        xEditFieldLabel             matlab.ui.control.Label
        yEditField                  matlab.ui.control.NumericEditField
        yEditField_2Label           matlab.ui.control.Label
        xEditField                  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        T % Tabela xlsx
        P % przerwanie petli
        Powrot0 %
        Kolejnosc %
        drog
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           assignin('base','S',1);
           set(app.Import,'Enable','off')
           set(app.SymulacjazplikuButton_2,'Enable','off')
           set(app.Zpowrotemdo0Button,'Enable','off')
           set(app.UtworzwykresButton,'Enable','off')
           app.P = 0;
           app.Powrot0 = 0;
           assignin('base','Powrot0', app.Powrot0)
           mdlWks = get_param('Manipulator','ModelWorkspace');
           y0 = getVariable(mdlWks,'y0');
           x0 = getVariable(mdlWks,'x0');
           z0 = getVariable(mdlWks,'z0');
          
           app.yEditField.Value = y0*sign(y0);
           app.xEditField.Value = x0*sign(x0);
           app.zEditField.Value = z0*sign(z0);
           Ty = 0;
           Tx = 0;
           Tz = 0;
           assignin('base',"Ty",Ty);
           assignin('base',"Tx",Tx);
           assignin('base',"Tz",Tz);
           app.drog = 0;
           
        end

        % Selection changed function: KolejnodziaaniaButtonGroup
        function KolejnodziaaniaButtonGroupSelectionChanged(app, event)
            V2 = app.ZXYButton.Value;
            V3 = app.SymultanicznieButton.Value;
            if (V3 == 1)
               assignin('base','S',0);
               app.Kolejnosc = 0;
            elseif V2 == 1
               assignin('base','S',-1);
               app.Kolejnosc = -1;
            else
                assignin('base','S',1);
                app.Kolejnosc = 1;
            end   
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.P = 1;
        end

        % Value changed function: ZplikuButton_2
        function ZplikuButton_2ValueChanged(app, event)
            value = app.ZplikuButton_2.Value;
            if value
            set(app.Import,'Enable','on')
            set(app.SymulacjaButton,'Enable','off')
            set(app.Zpowrotemdo0Button,'Enable','on')
            set(app.xEditField,'Editable','off')
            set(app.yEditField,'Editable','off')
            set(app.zEditField,'Editable','off')
            zPliku = 1;
            if isempty(app.T)
               set(app.SymulacjazplikuButton_2,'Enable','off')
               set(app.UtworzwykresButton,'Enable','off')
            else
               set(app.SymulacjazplikuButton_2,'Enable','on')
               set(app.UtworzwykresButton,'Enable','on')
            end
            else
            set(app.xEditField,'Editable','on')
            set(app.yEditField,'Editable','on')
            set(app.zEditField,'Editable','on')
            set(app.Import,'Enable','off')
            set(app.SymulacjazplikuButton_2,'Enable','off')
            set(app.SymulacjaButton,'Enable','on')
            set(app.Zpowrotemdo0Button,'Enable','off')
            set(app.UtworzwykresButton,'Enable','off')
            zPliku = 0;
            end
            app.numerkrokuEditField.Value = 0;
            assignin('base','zPliku',zPliku)
        end

        % Button pushed function: Import
        function ImportButtonPushed(app, event)
            [file,path]=uigetfile(('*.xlsx'), 'Choose a File');
            filename = [path file];
            app.T = readtable(filename,'VariableNamingRule',"preserve");
            assignin('base',"T",app.T);
            app.T = removevars(app.T,'Lp.');
            if isempty(app.T)
               set(app.SymulacjazplikuButton_2,'Enable','off')
               set(app.UtworzwykresButton,'Enable','off')
            else
               set(app.SymulacjazplikuButton_2,'Enable','on')
               set(app.UtworzwykresButton,'Enable','on')
            end

        end

        % Button pushed function: SymulacjazplikuButton_2
        function SymulacjazplikuButton_2Pushed(app, event)
         mdlWks = get_param('Manipulator','ModelWorkspace');
         Ty = 0;
         Tx = 0;
         Tz = 0;
         app.P = 0;
       
         app.numerkrokuEditField.Value = 0;
         if app.Powrot0 == 0
%1
         for n = 1:height(app.T)
             if app.P ~= 0
               break
             end
             app.numerkrokuEditField.Value = n;
             y = app.T{n,'Y'};
             x = app.T{n,'X'};
             z = app.T{n,'Z'};
             y0 = -getVariable(mdlWks,'y0');
             x0 = -getVariable(mdlWks,'x0');
             z0 = -getVariable(mdlWks,'z0');
           
           
         if (y < 0) || (y > 2)
             app.PozycjayEditField.Value = "Wartość spoza przedziału <0;2>";
             ny = 0;
         else
             ny = 1;
             app.PozycjayEditField.Value = "";
         end
         if (x < 0) || (x > 2)
             app.PozycjaxEditField.Value = "Wartość spoza przedziału <0;2>";
             nx = 0;
         else
             nx = 1;
             app.PozycjaxEditField.Value = "";
         end
         if (z < 0) || (z > 1)
             app.PozycjazEditField.Value = "Wartość spoza przedziału <0;1>";
             nz = 0;
         else
             nz = 1;
             app.PozycjazEditField.Value = "";
         end
        if (ny == 0) || (nx == 0) || (nz == 0)
            break
        
        end
        if (ny == 1) && (nx == 1) && (nz == 1)
         %Check if y greater or smaller than y0
         if y > y0
             yt = y - y0;
             Ty = 0;
             
         elseif y < y0
             yt = y0 - y;
             Ty = -1;
             
         else 
             yt = 0;
          
         end
         %Check if x greater or smaller than x0    
          if x > x0
             xt = x - x0;
             Tx = 0;
             
         elseif x < x0
             xt = x0 - x;
             Tx = -1;
              
         else 
             xt = 0;
             
          end
         %Check if z greater or smaller than y0
         if z > z0
             zt = z - z0;
             Tz = 0;
             
         elseif z < z0
             zt = z0 - z;
             Tz = -1;
              
         else 
             zt = 0;
             
         end
         assignin('base',"Ty",Ty);
         assignin('base',"Tx",Tx);
         assignin('base',"Tz",Tz);
         
         assignin('base','y1',-yt);
         assignin('base','x1',-xt);
         assignin('base','z1',-zt);
         
         app.yEditField.Value = y;
         app.xEditField.Value = x;
         app.zEditField.Value = z;
         
         set_param('Manipulator','SimulationCommand','start')
         
         %Changing values y0 x0 z0 in MODELWORKSPACE
             if yt ~= 0    
             
                 temp1y = -y;
             assignin(mdlWks,'y0',temp1y)
            
             end
             if xt ~= 0
                 
                 temp1x = -x;
             assignin(mdlWks,'x0',temp1x)
           
             end
             if zt ~= 0
                 
                 temp1z = -z;
             assignin(mdlWks,'z0',temp1z)
             
             end  
        S = evalin('base','S');
        if S == 0
           numbers = [yt xt zt]; % the array of numbers from which to search 
           maxVal  = max(numbers)+0.1;     % the highest number
           pause(maxVal);
        else
           pauza = xt + yt + zt +0.1;
           pause(pauza);
        end
        
        end
        
         end
         else
%2
            for n = 1:height(app.T)
             if app.P ~= 0
               break
             end
             app.numerkrokuEditField.Value = n;
             y = app.T{n,'Y'};
             x = app.T{n,'X'};
             z = app.T{n,'Z'};
             y0 = -getVariable(mdlWks,'y0');
             x0 = -getVariable(mdlWks,'x0');
             z0 = -getVariable(mdlWks,'z0');
             assignin('base',"S",app.Kolejnosc);
           
         if (y < 0) || (y > 2)
             app.PozycjayEditField.Value = "Wartość spoza przedziału <0;2>";
             ny = 0;
         else
             ny = 1;
             app.PozycjayEditField.Value = "";
         end
         if (x < 0) || (x > 2)
             app.PozycjaxEditField.Value = "Wartość spoza przedziału <0;2>";
             nx = 0;
         else
             nx = 1;
             app.PozycjaxEditField.Value = "";
         end
         if (z < 0) || (z > 1)
             app.PozycjazEditField.Value = "Wartość spoza przedziału <0;1>";
             nz = 0;
         else
             nz = 1;
             app.PozycjazEditField.Value = "";
         end
        if (ny == 0) || (nx == 0) || (nz == 0)
            break
        
        end
        if (ny == 1) && (nx == 1) && (nz == 1)
         %Check if y greater or smaller than y0
         if y > y0
             yt = y - y0;
             Ty = 0;
             
         elseif y < y0
             yt = y0 - y;
             Ty = -1;
             
         else 
             yt = 0;
          
         end
         %Check if x greater or smaller than x0    
          if x > x0
             xt = x - x0;
             Tx = 0;
             
         elseif x < x0
             xt = x0 - x;
             Tx = -1;
              
         else 
             xt = 0;
             
          end
         %Check if z greater or smaller than y0
         if z > z0
             zt = z - z0;
             Tz = 0;
             
         elseif z < z0
             zt = z0 - z;
             Tz = -1;
              
         else 
             zt = 0;
             
         end
         assignin('base',"Ty",Ty);
         assignin('base',"Tx",Tx);
         assignin('base',"Tz",Tz);
         
         assignin('base','y1',-yt);
         assignin('base','x1',-xt);
         assignin('base','z1',-zt);
         
         app.yEditField.Value = y;
         app.xEditField.Value = x;
         app.zEditField.Value = z;
         
         set_param('Manipulator','SimulationCommand','start')
         
         %Changing values y0 x0 z0 in MODELWORKSPACE
             if yt ~= 0    
             
                 temp1y = -y;
             assignin(mdlWks,'y0',temp1y)
            
             end
             if xt ~= 0
                 
                 temp1x = -x;
             assignin(mdlWks,'x0',temp1x)
           
             end
             if zt ~= 0
                 
                 temp1z = -z;
             assignin(mdlWks,'z0',temp1z)
             
             end  
        S = evalin('base','S');
        if S == 0
           numbers = [yt xt zt]; % the array of numbers from which to search 
           maxVal  = max(numbers)+0.1;     % the highest number
           pause(maxVal);
        else
           pauza = xt + yt + zt +0.1;
           pause(pauza);
        end
        
        end
%3
        if app.P ~= 0
               break
        end
        
             y = 0;
             x = 0;
             z = 0;
             y0 = -getVariable(mdlWks,'y0');
             x0 = -getVariable(mdlWks,'x0');
             z0 = -getVariable(mdlWks,'z0');
             
           if app.Kolejnosc ~= 0
           assignin('base',"S",-(app.Kolejnosc));
           else 
               
           end
           
           
         if (y < 0) || (y > 2)
             app.PozycjayEditField.Value = "Wartość spoza przedziału <0;2>";
             ny = 0;
         else
             ny = 1;
             app.PozycjayEditField.Value = "";
         end
         if (x < 0) || (x > 2)
             app.PozycjaxEditField.Value = "Wartość spoza przedziału <0;2>";
             nx = 0;
         else
             nx = 1;
             app.PozycjaxEditField.Value = "";
         end
         if (z < 0) || (z > 1)
             app.PozycjazEditField.Value = "Wartość spoza przedziału <0;1>";
             nz = 0;
         else
             nz = 1;
             app.PozycjazEditField.Value = "";
         end
        if (ny == 0) || (nx == 0) || (nz == 0)
            break
        
        end
        if (ny == 1) && (nx == 1) && (nz == 1)
         %Check if y greater or smaller than y0
         if y > y0
             yt = y - y0;
             Ty = 0;
             
         elseif y < y0
             yt = y0 - y;
             Ty = -1;
             
         else 
             yt = 0;
          
         end
         %Check if x greater or smaller than x0    
          if x > x0
             xt = x - x0;
             Tx = 0;
             
         elseif x < x0
             xt = x0 - x;
             Tx = -1;
              
         else 
             xt = 0;
             
          end
         %Check if z greater or smaller than y0
         if z > z0
             zt = z - z0;
             Tz = 0;
             
         elseif z < z0
             zt = z0 - z;
             Tz = -1;
              
         else 
             zt = 0;
             
         end
         assignin('base',"Ty",Ty);
         assignin('base',"Tx",Tx);
         assignin('base',"Tz",Tz);
         
         assignin('base','y1',-yt);
         assignin('base','x1',-xt);
         assignin('base','z1',-zt);
         
         app.yEditField.Value = y;
         app.xEditField.Value = x;
         app.zEditField.Value = z;
         
         set_param('Manipulator','SimulationCommand','start')
         
         %Changing values y0 x0 z0 in MODELWORKSPACE
             if yt ~= 0    
             
                 temp1y = -y;
             assignin(mdlWks,'y0',temp1y)
            
             end
             if xt ~= 0
                 
                 temp1x = -x;
             assignin(mdlWks,'x0',temp1x)
           
             end
             if zt ~= 0
                 
                 temp1z = -z;
             assignin(mdlWks,'z0',temp1z)
             
             end  
        S = evalin('base','S');
        if S == 0
           numbers = [yt xt zt]; % the array of numbers from which to search 
           maxVal  = max(numbers)+0.1;     % the highest number
           pause(maxVal);
        else
           pauza = xt + yt + zt +0.1;
           pause(pauza);
        end
        
        end
         
        
            end 
            
         end

        end

        % Button pushed function: SymulacjaButton
        function SymulacjaButtonPushed(app, event)
        mdlWks = get_param('Manipulator','ModelWorkspace');
         y0 = -getVariable(mdlWks,'y0');
         x0 = -getVariable(mdlWks,'x0');
         z0 = -getVariable(mdlWks,'z0');
         y = app.yEditField.Value;
         x = app.xEditField.Value;
         z = app.zEditField.Value;
         Ty = 0;
         Tx = 0;
         Tz = 0;
         ny = 0;
         nx = 0;
         nz = 0;

         
         if (y < 0) || (y > 2)
             app.PozycjayEditField.Value = "Wartość spoza przedziału <0;2>";
         else
             ny = 1;
             app.PozycjayEditField.Value = "";
         end
         if (x < 0) || (x > 2)
             app.PozycjaxEditField.Value = "Wartość spoza przedziału <0;2>";
         else
             nx = 1;
             app.PozycjaxEditField.Value = "";
         end
         if (z < 0) || (z > 1)
             app.PozycjazEditField.Value = "Wartość spoza przedziału <0;1>";
         else
             nz = 1;
             app.PozycjazEditField.Value = "";
         end
         
        if (ny == 1) && (nx == 1) && (nz == 1)
         %Check if y greater or smaller than y0
         if y > y0
             yt = y - y0;
             Ty = 0;
             
         elseif y < y0
             yt = y0 - y;
             Ty = -1;
             
         else 
             yt = 0;
          
         end
         %Check if x greater or smaller than x0    
          if x > x0
             xt = x - x0;
             Tx = 0;
             
         elseif x < x0
             xt = x0 - x;
             Tx = -1;
              
         else 
             xt = 0;
             
          end
         %Check if z greater or smaller than y0
         if z > z0
             zt = z - z0;
             Tz = 0;
             
         elseif z < z0
             zt = z0 - z;
             Tz = -1;
              
         else 
             zt = 0;
             
         end
         assignin('base',"Ty",Ty);
         assignin('base',"Tx",Tx);
         assignin('base',"Tz",Tz);
         
         assignin('base','y1',-yt);
         assignin('base','x1',-xt);
         assignin('base','z1',-zt);
         
         set_param('Manipulator','SimulationCommand','start')
         
         %Changing values y0 x0 z0 in MODELWORKSPACE
             if yt ~= 0    
             
                 temp1y = -y;
             assignin(mdlWks,'y0',temp1y)
            
             end
             if xt ~= 0
                 
                 temp1x = -x;
             assignin(mdlWks,'x0',temp1x)
           
             end
             if zt ~= 0
                 
                 temp1z = -z;
             assignin(mdlWks,'z0',temp1z)
             
             end
             S = evalin('base', 'S');
             if S == 0
             app.drog = app.drog+sqrt((xt^2)+(yt^2)+(zt^2));
             else
                 app.drog = app.drog + yt + xt + zt;
             end
             app.DrogamEditField.Value = app.drog;
             app.CzassEditField.Value = app.drog;  
        end
        
     
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             mdlWks = get_param('Manipulator','ModelWorkspace');
             S = evalin('base','S'); 
             assignin('base','S',0); 
             y0 = getVariable(mdlWks,'y0');
             x0 = getVariable(mdlWks,'x0');
             z0 = getVariable(mdlWks,'z0');
             Tx = -1;
             Ty = -1;
             Tz = -1;
             assignin('base',"Ty",Ty);
             assignin('base',"Tx",Tx);
             assignin('base',"Tz",Tz);
         
             assignin('base','y1',y0);
             assignin('base','x1',x0);
             assignin('base','z1',z0);
             
             set_param('Manipulator','SimulationCommand','start')
             
             assignin('base','S',S)
             assignin(mdlWks,'y0',0)
             assignin(mdlWks,'x0',0)
             assignin(mdlWks,'z0',0)
             app.yEditField.Value = 0;
             app.xEditField.Value = 0;
             app.zEditField.Value = 0;
             
             app.PozycjayEditField.Value = "";
             app.PozycjaxEditField.Value = "";
             app.PozycjazEditField.Value = "";
             app.numerkrokuEditField.Value = 0;
             app.drog = 0;
             app.DrogamEditField.Value = app.drog;
             app.CzassEditField.Value = app.drog;
        end

        % Value changed function: Zpowrotemdo0Button
        function Zpowrotemdo0ButtonValueChanged(app, event)
            value = app.Zpowrotemdo0Button.Value;
            
            if value
                app.Powrot0 = 1;
            else
                app.Powrot0 = 0;
            end
            assignin('base','Powrot0',app.Powrot0)
        end

        % Button pushed function: UtworzwykresButton
        function UtworzwykresButtonPushed(app, event)
            t = evalin('base','T');
            dane = table2array(t);
            dane = dane(:, 2:end);
            S = evalin('base','S');
            powrot = evalin('base','Powrot0');
            
            if S == 0
                close all
                dane_new = zeros(size(dane, 1)*2, size(dane,2));

                if powrot==1
                	for i=1:size(dane, 1)
                		dane_new(2*i-1, :) = dane(i, :);
                		dane_new(2*i, :) = [0, 0, 0];
                    end
              	%dane = dane_new;
            	    [czas, droga] = rysuj_zakres_symultaniczne(dane_new, 1, size(dane_new, 1), 0.1)
                else
            	    [czas, droga] = rysuj_zakres_symultaniczne(dane, 1, size(dane, 1), 0.1)
                end
                app.DrogamEditField.Value = droga;
                app.CzassEditField.Value = droga;
            else
            %% w 1 osi
            close all
            %1-X, 2-Y, 3-Z
            if S == -1
                osie = [3, 2, 1];
            elseif S == 1
                osie = [1, 2, 3];
            end
            czas_total=0;
            droga_total=0;
            for i=1:size(dane, 1)
            	if powrot
            		[czas, droga] = rysuj_zakres_1os([0, 0, 0; dane(i, :)], 1, 2, 0.1, powrot, osie);
            		czas_total = czas_total+czas;
            		droga_total = droga_total +droga;
                else
            		[czas, droga] = rysuj_zakres_1os(dane, 1, size(dane, 1), 0.1, powrot, osie)
            		break
                end
                if powrot
                    app.DrogamEditField.Value = droga_total;
                    app.CzassEditField.Value = droga_total;
                    czas_total
                    droga_total
                    
                else
                    app.DrogamEditField.Value = droga;
                    app.CzassEditField.Value = droga;
                end
            end
            if powrot
                
            else
                
            end
            return;
            end
            %%

            h=0.1;
            dane_wl = dane(1, :);
            w = 2;
            for i=1:size(dane, 1)-1
            	%wzgledne przesuniecie miedzy krokami:
            	deltaX = dane(i+1, 1) -dane(i, 1);
            	deltaY = dane(i+1, 2) -dane(i, 2);
            	deltaZ = dane(i+1, 3) -dane(i, 3);
            	delta = [deltaX, deltaY, deltaZ];
	

            	l_krokow_X = round_up(abs(deltaX/h), h);
            	l_krokow_Y = round_up(abs(deltaY/h), h);
            	l_krokow_Z = round_up(abs(deltaZ/h), h);
	
            	max_l_krokow = max([l_krokow_X, l_krokow_Y, l_krokow_Z]);

            	for k=1:max_l_krokow
            		for c=1:3
                        if dane_wl(w-1, c)*sign(delta(c))< dane(i+1, c)*sign(delta(c))
                            dane_wl(w, c) = dane(i, c)+k*h*sign(delta(c));
                        else
                            dane_wl(w, c) = dane(i+1, c);
                        end                        
                    end                  		
                    w=w+1;       
                end   
            end
            
       
            %%

            function [czas, droga] = rysuj_zakres_1os(dane, a, b, h, powrot, numery_osi)
	
                %numery_osi [3, 2, 1] Z -> Y -> X;
                %numery_osi [1, 3, 2] X -> Z -> Y;
                %h=0.1;
	
                if a==b
                    error("[RYSUJ ZAKRES] a = b, brak przedzialu do wyswietlenia.")
                end
	
                if a<1 || b<1
                    error("[RYSUJ ZAKRES] a lub b jest mniejsze niz 1");
                end
	
                if a>b
                    temp = a;
                    a=b;
                    b=temp;
                    warning("[RYSUJ ZAKRES] Zamieniono zmienna a z b");
                end
	
                if b>size(dane, 1)
                    error("[RYSUJ ZAKRES] b poza zakresem!");
                end	
	
                dane = dane(a:b, :);
                dane_wl = dane(1, :);
                w=2;
                droga=0;
	
                for i=1:size(dane, 1)-1
		
                    for wybor_osi = numery_osi
			
                        deltaX = dane(i+1, 1) - dane(i, 1);
                        deltaY = dane(i+1, 2) - dane(i, 2);
                        deltaZ = dane(i+1, 3) - dane(i, 3);
                        delta = [deltaX, deltaY, deltaZ];
                    	
			
                        l_krokow_X = round_up(abs(deltaX/h), h);
                        l_krokow_Y = round_up(abs(deltaY/h), h);
                        l_krokow_Z = round_up(abs(deltaZ/h), h);
			
                        kroki = [l_krokow_X, l_krokow_Y, l_krokow_Z];
                        droga = droga+abs(delta(wybor_osi));
			
                        for k = 1:kroki(wybor_osi)
                            dane_wl(w, :) = dane_wl(w-1, :);
                            
                            if dane_wl(w-1, wybor_osi)*sign(delta(wybor_osi))< dane(i+1, wybor_osi)*sign(delta(wybor_osi))
                                dane_wl(w, wybor_osi) = dane(i, wybor_osi)+k*h*sign(delta(wybor_osi));
                            else
                                dane_wl(w, wybor_osi) = dane(i+1, wybor_osi);
                            end
                            w=w+1;
                        end
                    end
                end
                
                if powrot 
                    dane_wl = [dane_wl; dane_wl(end:-1:1, :)];
                    droga = droga*2;
                end
                disp(dane_wl)
                plot3(dane_wl(:, 1), dane_wl(:, 2), dane_wl(:, 3), '-bx')
                hold on
	
                xlabel("x")
                ylabel("y")
                zlabel("z")
                set(gca,'YDir','reverse')
                set(gca,'ZDir','reverse')
                if S == 1
                    if powrot
                        set(gcf, 'Name', 'Przemieszczenia X->Y->Z z powrotem do 0')
                    else
                        set(gcf, 'Name', 'Przemieszczenia X->Y->Z')
                    end
                elseif S == -1
                    if powrot
                        set(gcf, 'Name', 'Przemieszczenia Z->Y->X z powrotem do 0')
                    else
                        set(gcf, 'Name', 'Przemieszczenia Z->Y->X')
                    end
                end
                czas = size(dane_wl, 1)-1;
            end
            %%
            function [czas, droga] = rysuj_zakres_symultaniczne(dane, a, b, h)

	%h=0.1;
	if a==b
		error("[RYSUJ ZAKRES] a = b, brak przedzialu do wyswietlenia.")
	end
	if a<1 || b<1
		error("[RYSUJ ZAKRES] a lub b jest mniejsze niz 1")
	end
	if a>b
		temp = a;
		a=b;
		b=temp;
		warning("[RYSUJ ZAKRES] Zamieniono zmienna a z b");
	end
	if b>size(dane, 1)
		error("[RYSUJ ZAKRES] b poza zakresem!");
	end
	
	dane = dane(a:b, :);
	dane_wl = dane(1, :);

	w = 2;
	droga=0;
	for i=1:size(dane, 1)-1
		%wzgledne przesuniecie miedzy krokami:
		deltaX = dane(i+1, 1) -dane(i, 1);
		deltaY = dane(i+1, 2) -dane(i, 2);
		deltaZ = dane(i+1, 3) -dane(i, 3);
		delta = [deltaX, deltaY, deltaZ];

		l_krokow_X = round_up(abs(deltaX/h), h);
		l_krokow_Y = round_up(abs(deltaY/h), h);
		l_krokow_Z = round_up(abs(deltaZ/h), h);
		
		max_l_krokow = max([l_krokow_X, l_krokow_Y, l_krokow_Z]);
		for k=1:max_l_krokow
			for c=1:3
				if dane_wl(w-1, c)*sign(delta(c))< dane(i+1, c)*sign(delta(c))
					dane_wl(w, c) = dane(i, c)+k*h*sign(delta(c));
				else
					dane_wl(w, c) = dane(i+1, c);
				end
			end
			droga=droga+sqrt(sum((dane_wl(w, :)-dane_wl(w-1, :)).^2));
			w=w+1;
		end
	end
% 	if powrot 
% 		dane_wl = [dane_wl; dane_wl(end:-1:1, :)];
% 	end
% 	disp(dane_wl)
    
	plot3(dane_wl(:, 1), dane_wl(:, 2), dane_wl(:, 3), '-bx')
	hold on
	xlabel("x")
	ylabel("y")
	zlabel("z")
    set(gca,'YDir','reverse')
    set(gca,'ZDir','reverse')
    if powrot
          set(gcf, 'Name', 'Przemieszczenia Symultanicznie z powrotem do 0')
    else
          set(gcf, 'Name', 'Przemieszczenia Symultanicznie')
    end

	czas = size(dane_wl, 1)-1;

end
            function x_wy = round_up(x, dokladnosc)	
                reszta = mod(x, dokladnosc);
                if reszta == 0
                    x_wy=x;
                    return
                end
                wynik = x-reszta;
                x_wy = wynik+dokladnosc;
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditField_2Label = uilabel(app.UIFigure);
            app.yEditField_2Label.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SymulacjaButton = uibutton(app.UIFigure, 'push');
            app.SymulacjaButton.ButtonPushedFcn = createCallbackFcn(app, @SymulacjaButtonPushed, true);
            app.SymulacjaButton.Text = 'Symulacja';
            app.KolejnodziaaniaButtonGroup = uibuttongroup(app.UIFigure);
            app.KolejnodziaaniaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @KolejnodziaaniaButtonGroupSelectionChanged, true);
            app.KolejnodziaaniaButtonGroup.Title = 'Kolejność działania';
            app.XYZButton = uitogglebutton(app.KolejnodziaaniaButtonGroup);
            app.XYZButton.Text = 'X->Y->Z';
            app.XYZButton.Value = true;
            app.ZXYButton = uitogglebutton(app.KolejnodziaaniaButtonGroup);
            app.ZXYButton.Text = 'Z->X->Y';
            app.SymultanicznieButton = uitogglebutton(app.KolejnodziaaniaButtonGroup);
            app.SymultanicznieButton.Text = 'Symultanicznie';
            app.PozycjayEditField = uieditfield(app.UIFigure, 'text');
            app.PozycjayEditField.Editable = 'off';
            app.PozycjaxEditField = uieditfield(app.UIFigure, 'text');
            app.PozycjaxEditField.Editable = 'off';
            app.PozycjazEditField = uieditfield(app.UIFigure, 'text');
            app.PozycjazEditField.Editable = 'off';
            app.Import = uibutton(app.UIFigure, 'push');
            app.Import.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.Import.Icon = 'download-symbol.png';
            app.Import.Text = '';
            app.ImportzplikuxlsxLabel = uilabel(app.UIFigure);
            app.ImportzplikuxlsxLabel.Text = 'Import z pliku .xlsx';
            app.numerkrokuEditFieldLabel = uilabel(app.UIFigure);
            app.numerkrokuEditFieldLabel.Text = 'numer kroku';
            app.numerkrokuEditField = uieditfield(app.UIFigure, 'numeric');
            app.UtworzwykresButton = uibutton(app.UIFigure, 'push');
            app.UtworzwykresButton.ButtonPushedFcn = createCallbackFcn(app, @UtworzwykresButtonPushed, true);
            app.UtworzwykresButton.Text = 'Utworz wykres';
            app.SymulacjazplikuButton_2 = uibutton(app.UIFigure, 'push');
            app.SymulacjazplikuButton_2.ButtonPushedFcn = createCallbackFcn(app, @SymulacjazplikuButton_2Pushed, true);
            app.SymulacjazplikuButton_2.Text = 'Symulacja z pliku';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ZplikuButton_2 = uibutton(app.UIFigure, 'state');
            app.ZplikuButton_2.ValueChangedFcn = createCallbackFcn(app, @ZplikuButton_2ValueChanged, true);
            app.ZplikuButton_2.Text = 'Z pliku';
            app.UwagiLabel = uilabel(app.UIFigure);
            app.UwagiLabel.Text = 'Uwagi:';
            app.Zpowrotemdo0Button = uibutton(app.UIFigure, 'state');
            app.Zpowrotemdo0Button.ValueChangedFcn = createCallbackFcn(app, @Zpowrotemdo0ButtonValueChanged, true);
            app.Zpowrotemdo0Button.Text = 'Z powrotem do 0';
            app.DrogamEditFieldLabel = uilabel(app.UIFigure);
            app.DrogamEditFieldLabel.Text = 'Droga [m]';
            app.DrogamEditField = uieditfield(app.UIFigure, 'numeric');
            app.DrogamEditField.Editable = 'off';
            app.CzassEditFieldLabel = uilabel(app.UIFigure);
            app.CzassEditFieldLabel.Text = 'Czas [s]';
            app.CzassEditField = uieditfield(app.UIFigure, 'numeric');
            app.CzassEditField.Editable = 'off';
            app.DaneprzywczytywaniuzplikupojawiajasiepoutworzeniuwykresuLabel = uilabel(app.UIFigure);
            app.DaneprzywczytywaniuzplikupojawiajasiepoutworzeniuwykresuLabel.Text = {'Dane przy '; 'wczytywaniu z pliku '; 'pojawiaja sie po '; 'utworzeniu wykresu.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Strowanie

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end